classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        alphaEditField                matlab.ui.control.NumericEditField
        alphaLabel                    matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        PatienceEditField             matlab.ui.control.NumericEditField
        PatienceEditFieldLabel        matlab.ui.control.Label
        PROJECTButton                 matlab.ui.control.Button
        RelaxationCheckBox            matlab.ui.control.CheckBox
        SARTUseWindowCheckBox         matlab.ui.control.CheckBox
        PhantomNameEditField          matlab.ui.control.EditField
        PhantomNameLabel              matlab.ui.control.Label
        NumberofDetectorsEditField    matlab.ui.control.NumericEditField
        NumberofDetectorsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField   matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        SourcetoDetectordistanceEditField  matlab.ui.control.NumericEditField
        SourcetoDetectordistanceEditFieldLabel  matlab.ui.control.Label
        ProjectionAngleStepSizeEditField  matlab.ui.control.NumericEditField
        ProjectionAngleStepSizeLabel  matlab.ui.control.Label
        LengthoftheDetectorEditField  matlab.ui.control.NumericEditField
        LengthoftheDetectorEditFieldLabel  matlab.ui.control.Label
        UIAxes_8                      matlab.ui.control.UIAxes
        UIAxes_7                      matlab.ui.control.UIAxes
        UIAxes_6                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PROJECTButton
        function PROJECTButtonPushed(app, event)
            %%%%%  THIS PLACE IS GOING TO BE FILLED
            phantom_name = app.PhantomNameEditField.Value;
            I = struct2array(load(phantom_name));
            N_pix = size(I,1);
            show_plot = 1;
            L_det = app.LengthoftheDetectorEditField.Value;
            N_det = app.NumberofDetectorsEditField.Value;
            n_iter = app.NumberofIterationsEditField.Value;
            source2det_dist = app.SourcetoDetectordistanceEditField.Value;
            angle_step = app.ProjectionAngleStepSizeEditField.Value;
            patience = app.PatienceEditField.Value;
            alpha = app.alphaEditField.Value;
            if_window = app.SARTUseWindowCheckBox.Value;
            if_relax = app.RelaxationCheckBox.Value;

            xlim(app.UIAxes,[1,N_pix])
            xlim(app.UIAxes_5,[1,N_pix])
            xlim(app.UIAxes_6,[1,N_pix])
            xlim(app.UIAxes_7,[1,N_pix])
            ylim(app.UIAxes,[1,N_pix])
            ylim(app.UIAxes_5,[1,N_pix])
            ylim(app.UIAxes_6,[1,N_pix])
            ylim(app.UIAxes_7,[1,N_pix])


            [PROJECTIONS, ~] = radon_project(phantom_name,L_det, N_det, angle_step, source2det_dist);
            app.Label.BackgroundColor = 'white';
            app.Label.FontSize = 20;
            app.Label.Text = ['[INFO]' newline 'PROJECTION COMPLETED'];


            [bart_IMAGE, bart_errors] = binary_weighted_art(N_pix, N_pix, PROJECTIONS, L_det, source2det_dist, n_iter, show_plot, I, patience,alpha, 0,app.UIAxes);
            app.Label_2.BackgroundColor = 'white';
            app.Label_2.FontSize = 20;
            app.Label_2.Text = ['[INFO]' newline 'ART COMPLETED' newline ['SSIM:',num2str(ssim(I,bart_IMAGE))]];
            plot(app.UIAxes_4, bart_errors)
            xlabel(app.UIAxes_4,'Iterations')
            title(app.UIAxes_4,'Relative Error vs Iterations')

            [art_IMAGE, art_errors] = art(N_pix, N_pix, PROJECTIONS, L_det, source2det_dist, n_iter, show_plot, I, patience, alpha, app.UIAxes_5);
            app.Label_3.BackgroundColor = 'white';
            app.Label_3.FontSize = 20;
            app.Label_3.Text = ['[INFO]' newline 'MART COMPLETED' newline ['SSIM:',num2str(ssim(I,art_IMAGE))] ];
            plot(app.UIAxes_3, art_errors)
            xlabel(app.UIAxes_3,'Iterations')
            title(app.UIAxes_3,'Relative Error vs Iterations')

            [~, sirt_IMAGE, sirt_errors] = sirtv2(N_pix, N_pix, PROJECTIONS, L_det, source2det_dist, n_iter, show_plot, I, patience, app.UIAxes_6);
            app.Label_4.BackgroundColor = 'white';
            app.Label_4.FontSize = 20;
            app.Label_4.Text = ['[INFO]' newline 'SIRT COMPLETED' newline ['SSIM:',num2str(ssim(I,(sirt_IMAGE)))]];
            plot(app.UIAxes_2, sirt_errors)
            xlabel(app.UIAxes_2,'Iterations')
            title(app.UIAxes_2,'Relative Error vs Iterations')

            [sart_IMAGE, sart_errors] = sart(phantom_name,L_det,N_det,angle_step,source2det_dist,if_window, if_relax, n_iter, 1, patience, app.UIAxes_7);
            app.Label_5.BackgroundColor = 'white';
            app.Label_5.FontSize = 20;
            app.Label_5.Text = ['[INFO]' newline 'SART COMPLETED' newline ['SSIM:',num2str(ssim(I,sart_IMAGE))]];
            plot(app.UIAxes_8, sart_errors)
            xlabel(app.UIAxes_8,'Iterations')
            title(app.UIAxes_8,'Relative Error vs Iterations')


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ART')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Relative Error')
            xlabel(app.UIAxes_2, 'Iterations')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Relative Error')
            xlabel(app.UIAxes_3, 'Iterations')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Relative Error')
            xlabel(app.UIAxes_4, 'Iterations')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Modified ')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'SIRT')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'SART')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Relative Error')
            xlabel(app.UIAxes_8, 'Iterations')
            zlabel(app.UIAxes_8, 'Z')
            app.LengthoftheDetectorEditFieldLabel = uilabel(app.UIFigure);
            app.LengthoftheDetectorEditFieldLabel.Text = {'Length of the'; 'Detector'};
            app.LengthoftheDetectorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectionAngleStepSizeLabel = uilabel(app.UIFigure);
            app.ProjectionAngleStepSizeLabel.Text = {'Projection Angle'; 'Step Size'};
            app.ProjectionAngleStepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SourcetoDetectordistanceEditFieldLabel = uilabel(app.UIFigure);
            app.SourcetoDetectordistanceEditFieldLabel.Text = {'Source to '; 'Detector distance'};
            app.SourcetoDetectordistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDetectorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDetectorsEditFieldLabel.Text = {'Number of '; 'Detectors'};
            app.NumberofDetectorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhantomNameLabel = uilabel(app.UIFigure);
            app.PhantomNameLabel.Text = 'Phantom Name';
            app.PhantomNameEditField = uieditfield(app.UIFigure, 'text');
            app.SARTUseWindowCheckBox = uicheckbox(app.UIFigure);
            app.SARTUseWindowCheckBox.Text = 'SART: Use Window';
            app.RelaxationCheckBox = uicheckbox(app.UIFigure);
            app.RelaxationCheckBox.Text = 'Relaxation';
            app.PROJECTButton = uibutton(app.UIFigure, 'push');
            app.PROJECTButton.ButtonPushedFcn = createCallbackFcn(app, @PROJECTButtonPushed, true);
            app.PROJECTButton.Text = 'PROJECT';
            app.PatienceEditFieldLabel = uilabel(app.UIFigure);
            app.PatienceEditFieldLabel.Text = 'Patience';
            app.PatienceEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.alphaLabel = uilabel(app.UIFigure);
            app.alphaLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end